classdef robot_marge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Joint3Slider       matlab.ui.control.Slider
        Joint3SliderLabel  matlab.ui.control.Label
        RobotMargeLabel    matlab.ui.control.Label
        Joint1Slider       matlab.ui.control.Slider
        Joint1SliderLabel  matlab.ui.control.Label
        Joint2Slider       matlab.ui.control.Slider
        Joint2SliderLabel  matlab.ui.control.Label
        CreateRobotButton  matlab.ui.control.Button
        axes1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = [];
        joint_params = [0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateRobotButton
        function CreateRobotButtonPushed(app, event)
            L(1) = Link('revolute', 'd', 3.2, 'a', 10.5, 'alpha', 0, 'offset', 8*pi/180);
            L(2) = Link('revolute', 'd', 0.5, 'a', 10.3, 'alpha', 180*pi/180, 'offset', 25*pi/250');
            L(3) = Link('prismatic', 'theta', 0, 'a', 0, 'alpha', 0, 'offset', -3);

            L(3).qlim = [0 9];

            app.robot = SerialLink(L, 'name', 'robot');
            app.robot.plot(app.joint_params);
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            if isempty(app.robot)
                return
            end
            
            app.joint_params(1) = deg2rad(app.Joint1Slider.Value);
            app.joint_params(2) = deg2rad(app.Joint2Slider.Value);
            app.joint_params(3) = app.Joint3Slider.Value;

            % Plot the robot
            app.robot.plot(app.joint_params);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            if isempty(app.robot)
                return
            end
            
            app.joint_params(1) = deg2rad(app.Joint1Slider.Value);
            app.joint_params(2) = deg2rad(app.Joint2Slider.Value);
            app.joint_params(3) = app.Joint3Slider.Value;

            % Plot the robot
            app.robot.plot(app.joint_params);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            if isempty(app.robot)
                return
            end

            app.joint_params(1) = deg2rad(app.Joint1Slider.Value);
            app.joint_params(2) = deg2rad(app.Joint2Slider.Value);
            app.joint_params(3) = app.Joint3Slider.Value;

            % Plot the robot
            app.robot.plot(app.joint_params);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Robot Marge')
            app.axes1.Tag = 'axes1';
            colormap(app.axes1, 'sky')
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobotButtonPushed, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.MajorTicks = [0 50 100];
            app.Joint2Slider.MajorTickLabels = {'0', '50', '100'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.MajorTicks = [0 50 100];
            app.Joint1Slider.MajorTickLabels = {'0', '50', '100'};
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.RobotMargeLabel = uilabel(app.UIFigure);
            app.RobotMargeLabel.Text = 'Robot Marge';
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [0 3.4];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.MajorTickLabels = {''};
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_marge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end